classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        GatePositionKnob                matlab.ui.control.DiscreteKnob
        GatePositionKnobLabel           matlab.ui.control.Label
        CheckforPassengersButton        matlab.ui.control.Button
        TotalRidersLabel                matlab.ui.control.Label
        WindBlueRollerCoasterRideLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        a; %arduino
        s1;  % servo Motor
    
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.a;
            clear app.s1
            app.a = arduino();
            app.s1 = servo(app.a, 'D9', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);
            app.TotalRidersLabel.Text = ('Gate up, Loading can Begin');

        end

        % Button pushed function: CheckforPassengersButton
        function CheckforPassengersButtonPushed(app, event)
        
            TotalNumRiders = 0;
           writePosition(app.s1, 0.5)
           app.GatePositionKnob.Value = ('Gate Down');
            pause(2)
            while TotalNumRiders < 5
                sensor = readVoltage(app.a,'A0');
          
                
                if sensor < 3.0
                    app.writePosition(app.s1, 1);
                    app.GatepositionKnob.Value = ('Gate up');
                    totalNumRiders = totalNumRiders +1;
                  
                    app.TotalRidersLabel.Text = num2str(totalNumRiders);
                    pause(2)
                    app.GatepositionKnob.Value = ('gate locked');
                    app.TotalRidersLabel.Text = num2str('Ride Full');
                end

            end

        end

        % Value changed function: GatePositionKnob
        function GatePositionKnobValueChanged(app, event)
             Value = app.GatePositionKnob.Value;
            TotalNumRiders = 0;
            writePosition(app.s1, 0.5)
            app.GatePositionKnob.Value = ('Gate down');
            pause(2)
            while TotalNumRiders < 5
                sensor = readVoltage(app.a,'A0');
          
                
                if sensor < 3.0
                    writePosition(app.s1, 1);
                    app.GatepositionKnob.Value = ('Gate up');
                    totalNumRiders = totalNumRiders +1;
                  
                    app.TotalRidersLabel.Text = num2str(totalNumRiders);
                    pause(2)
                    app.GatepositionKnob.Value = ('gate locked');
                    app.TotalRidersLabel.Text = num2str('Ride Full');
                end
                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WindBlueRollerCoasterRideLabel = uilabel(app.UIFigure);
            app.WindBlueRollerCoasterRideLabel.Text = 'Wind Blue Roller Coaster Ride';
            app.TotalRidersLabel = uilabel(app.UIFigure);
            app.TotalRidersLabel.Text = 'Total Riders';
            app.CheckforPassengersButton = uibutton(app.UIFigure, 'push');
            app.CheckforPassengersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforPassengersButtonPushed, true);
            app.CheckforPassengersButton.Text = 'Check for Passengers';
            app.GatePositionKnobLabel = uilabel(app.UIFigure);
            app.GatePositionKnobLabel.Text = 'Gate Position';
            app.GatePositionKnob = uiknob(app.UIFigure, 'discrete');
            app.GatePositionKnob.Items = {'Gate up', 'Gate Down', 'Gate locked'};
            app.GatePositionKnob.ValueChangedFcn = createCallbackFcn(app, @GatePositionKnobValueChanged, true);
            app.GatePositionKnob.Value = 'Gate up';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Roller Coaster .jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end